classdef stc_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        channelListBoxLabel  matlab.ui.control.Label
        channelListBox       matlab.ui.control.ListBox
        TabGroup             matlab.ui.container.TabGroup
        stimTab              matlab.ui.container.Tab
        STATab               matlab.ui.container.Tab
        STA_Axes_temporal    matlab.ui.control.UIAxes
        STA_Axes_hist        matlab.ui.control.UIAxes
        infoLabel            matlab.ui.control.Label
        STA_Text             matlab.ui.control.TextArea
        Panel_STA_slice      matlab.ui.container.Panel
        STCTab               matlab.ui.container.Tab
        infoTextArea         matlab.ui.control.TextArea
        STC_Axes_ev          matlab.ui.control.UIAxes
        STC_Axes_hist        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        channel_names
        selected_channel_index
        
%         stim             % input stim
%         spike_train      % measured spike response
        
        sta_all_channels
        sta_num_samples
        
        height
        width
        
        sampling_rate
        gridT
        
        %sta_num_samples
        %sta_result

        stc_u                       %                   1x156              8569872  cell         
        stc_ev                      %                   1x156               116776  cell                                                 
        stc_score                   %                   1x156            162471552  cell                                                 
        stc_significant_ev_idx      %                   1x156                18104  cell                                                 
  
        
    end
    
    methods (Access = private)

%         function plot_spike_train(app)
%             bar(app.spike_Axes, app.spike_train(:,app.selected_channel_index))
%         end
    
    
%         function run_sta(app) %, stim, spike_train, sta_num_samples)
%             % run STA
%             app.sta_result = calc_STA_and_STC(app.stim, app.spike_train(:,app.selected_channel_index), app.sta_num_samples);
%             
%             % plot
%             plot(app.STA_Axes_temporal, app.sta_result)
%             
%         end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %% PLOTTING FUNCTIONS ARE HERE
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function plot_sta(app)

            sta = app.sta_all_channels{app.selected_channel_index};
            sta = reshape(sta, app.sta_num_samples, []);
            
            % plot temporal profile of STA
            ax = app.STA_Axes_temporal;
            grid = app.gridT*1000;
            plot(ax, grid, sta)
            
%             app.STA_Axes_temporal.XLabel = 'ms'
%             app.STA_Axes_temporal.YLabel = 'stim intensity'
            ax.YLim = [0 1];
            
            % plot histogram of STA
            ax = app.STA_Axes_hist
            bins = 0:0.025:1;
            histogram(ax, sta(:), bins);

            app.STA_Text.Value = sprintf('m=%.2f, sig=%.2f', mean(sta(:)),std(sta(:)));
           
%           % plot spatial profile of STA   
            plot_sta_slices(app, app.Panel_STA_slice, sta)
        end

        function plot_sta_slices(app, fig, sta)
            
            [max_val, max_slice_idx] =  max(max(sta, [], 2));
            [min_val, min_slice_idx] =  min(min(sta, [], 2));
            
            num_slices = size(sta,1);

            r = 2;
            %r=floor(sqrt(num_slices));
            c=ceil(num_slices/r);
            
            fig.AutoResizeChildren = 'off';
            for cnt=1:num_slices
                ax = subplot(r,c,cnt,'Parent', fig);
                FLIP_XY = true;
                plot_sta_one_slice(app, ax, sta(cnt,:), FLIP_XY);
                
                % add title
                ax.Title.String = sprintf('%.0f ms',app.gridT(cnt)*1000);
                if cnt == max_slice_idx
                    ax.Title.String = [ax.Title.String  ' (pos. peak)'];
                end
                if cnt == min_slice_idx
                    ax.Title.String = [ax.Title.String  ' (neg. peak)'];
                end
                ax.Title.FontWeight = 'normal';
              
            end            
            
        end
        
        function plot_sta_one_slice(app, ax, slice, FLIP_XY)
            if FLIP_XY
                imagesc(reshape(slice, app.height, app.width)', 'Parent', ax)
            else
                imagesc(reshape(slice, app.height, app.width), 'Parent', ax)
            end
            ax.XLim = [1 app.width];
            ax.YLim = [1 app.height];
            ax.CLim = [0.2 0.8];            
            ax.Colormap = gray;
            
            axis(ax,'off')
            axis(ax,'xy')
            
        end
        
    
        function results = plot_stc(app)
            % plot eigen values
            ev = app.stc_ev{app.selected_channel_index};            
            stc_significant_ev_idx = app.stc_significant_ev_idx{app.selected_channel_index}


            ax = app.STC_Axes_ev;
            plot(ax, ev, 'o:', 'MarkerSize', 5);
            if ~isempty(stc_significant_ev_idx)
                app.infoTextArea.Value = sprintf('%d ', stc_significant_ev_idx);
            
                hold (ax, 'on')
                plot(ax, stc_significant_ev_idx, ev(stc_significant_ev_idx), '*r', 'MarkerSize', 10);
                hold (ax, 'off')
            end
            
            % plot histogram of eigen values
            ax = app.STC_Axes_hist;
%             bins = 0:0.025:1;
            histogram(ax, ev);


        end
        
%         function results = plot_STA(app, channel_index)
%             %app.infoTextArea.Value = ['function called with '  channel_name]
%             plot(app.STA_Axes_temporal, [1 2 3 4], [1 2 3 4], '-or')
%             
%         end
    
        function str = print_results(app)
            str = sprintf('ch name=%s',  app.channel_names{app.selected_channel_index});
            str = [str ', ' sprintf('ch index=%d', app.selected_channel_index)];
            
            app.infoTextArea.Value = str;
        end
        

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            
            % load channel names from the workspace
            app.channel_names = evalin('base','channel_names');
            app.channelListBox.Items = app.channel_names;
            app.channelListBox.ItemsData = 1:length(app.channel_names);


%             % load spike train data from the workspace
%             app.stim = evalin('base','stim');
%             
%             % load spike train data from the workspace
%             app.spike_train = evalin('base','spike_train');
           
            % load sta results for all channels
            app.sta_all_channels = evalin('base','sta_all_channels');
            app.sta_num_samples = evalin('base','sta_num_samples');
            app.height = sqrt(size(app.sta_all_channels{1},2)/app.sta_num_samples);
            app.width = app.height
            
            % sta param
            %app.sta_num_samples = 20;
            
            app.sampling_rate = evalin('base', 'sampling_rate');
            app.gridT = (-app.sta_num_samples+1:0)/app.sampling_rate;
            
            
            % load stc results
            app.stc_u = evalin('base', 'stc_u');
            app.stc_ev = evalin('base', 'stc_ev');
            app.stc_significant_ev_idx = evalin('base', 'stc_significant_ev_idx');
                        
            
            app.infoTextArea.Value = 'Ready to run!';
            
            
            % run for the first imte
            app.selected_channel_index = 1;
            
            %plot_spike_train(app)
            
            % plot STA
            plot_sta(app)
            
            % plot STC
            plot_stc(app)
            
        end

        % Value changed function: channelListBox
        function channelListBoxValueChanged(app, event)
            %% get selected channel index
            app.selected_channel_index = app.channelListBox.Value
            
            % plot spike train
            %plot_spike_train(app)
            
            % plot sta
            plot_sta(app)
                                    
            % plot stc
            plot_stc(app)
            
            % print results
            print_results(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.channelListBoxLabel = uilabel(app.UIFigure);
            app.channelListBoxLabel.Text = 'channel';
            app.channelListBox = uilistbox(app.UIFigure);
            app.channelListBox.ValueChangedFcn = createCallbackFcn(app, @channelListBoxValueChanged, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.stimTab = uitab(app.TabGroup);
            app.stimTab.Title = 'stim';
            app.STATab = uitab(app.TabGroup);
            app.STATab.Title = 'STA';
            app.STA_Axes_temporal = uiaxes(app.STATab);
            title(app.STA_Axes_temporal, 'temporal')
            xlabel(app.STA_Axes_temporal, 'time prior to spike onse (ms)')
            ylabel(app.STA_Axes_temporal, 'stim intensity')
            app.STA_Axes_hist = uiaxes(app.STATab);
            title(app.STA_Axes_hist, 'histogram')
            xlabel(app.STA_Axes_hist, 'sta')
            ylabel(app.STA_Axes_hist, 'count')
            app.infoLabel = uilabel(app.STATab);
            app.infoLabel.Text = 'info';
            app.STA_Text = uitextarea(app.STATab);
            app.Panel_STA_slice = uipanel(app.STATab);
            app.Panel_STA_slice.Title = 'spatial';
            app.STCTab = uitab(app.TabGroup);
            app.STCTab.Title = 'STC';
            app.STC_Axes_ev = uiaxes(app.STCTab);
            title(app.STC_Axes_ev, '')
            xlabel(app.STC_Axes_ev, 'index')
            ylabel(app.STC_Axes_ev, 'eigen value')
            app.STC_Axes_hist = uiaxes(app.STCTab);
            title(app.STC_Axes_hist, 'histogram')
            xlabel(app.STC_Axes_hist, 'eigen value')
            ylabel(app.STC_Axes_hist, 'count')
            app.infoTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = stc_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end